classdef segmentationInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ClosingCountourDropDownLabel   matlab.ui.control.Label
        ClosingCountourDropDown        matlab.ui.control.DropDown
        ProcessingoptionsLabel         matlab.ui.control.Label
        FilterDropDownLabel            matlab.ui.control.Label
        FilterDropDown                 matlab.ui.control.DropDown
        DataLabel                      matlab.ui.control.Label
        RatioPixelMeterEditFieldLabel  matlab.ui.control.Label
        RatioPixelMeterEditField       matlab.ui.control.NumericEditField
        GeometryLabel                  matlab.ui.control.Label
        DistancebetweenslicesmEditFieldLabel  matlab.ui.control.Label
        DistancebetweenslicesmEditField  matlab.ui.control.NumericEditField
        SelectOutputfolderButton       matlab.ui.control.Button
        SelectInputUSImagesButton      matlab.ui.control.Button
        SelectInputMaskImagesButton    matlab.ui.control.Button
        SaveMasksCheckBox              matlab.ui.control.CheckBox
        UITable                        matlab.ui.control.Table
        UITable_2                      matlab.ui.control.Table
        OutputLabel                    matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        SegmentationSwitchLabel        matlab.ui.control.Label
        SegmentationSwitch             matlab.ui.control.Switch
        LayerTumorEditFieldLabel       matlab.ui.control.Label
        LayerTumorEditField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        done; % Description
    end
    
    properties (Access = private)
        images;
        masks;
        imagesFolder;
        masksFolder;
       
        outputPath;
        end
    
    methods (Access = public)
        
        function results = getParameters(app)
            results.images = app.images;
            results.masks = app.masks;
            results.imagesFolder = app.imagesFolder;
            results.masksFolder = app.masksFolder;
            results.outputPath = app.outputPath;
            
            results.flagsave = app.SaveMasksCheckBox.Value;
            results.ratioPixelM = app.RatioPixelMeterEditField.Value;
            results.distanceBetweenLayers = app.DistancebetweenslicesmEditField.Value;
            results.closedOption = app.ClosingCountourDropDown.Value;
            results.filter = app.FilterDropDown.Value;
            results.tumor = app.SegmentationSwitch.Value;
            results.idx_layer_tumor = app.LayerTumorEditField.Value;
        end
   
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, done)
            app.done = 0;
        end

        % Button pushed function: SelectOutputfolderButton
        function SelectOutputfolderButtonPushed(app, event)
           app.outputPath=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.SelectOutputfolderButton.Text=app.outputPath;  % Sets the label text to be the selected path


        end

        % Button pushed function: SelectInputUSImagesButton
        function SelectInputUSImagesButtonPushed2(app, event)
            [app.images, app.imagesFolder] =uigetfile({'*.jpg;*.png;*.tif;*.bmp;',...
    'Images Files (*.jpg;*.png;*.tif;*.bmp)'}, 'MultiSelect', 'on');                    % Calls 'uigetdir' to obtain the directory location from the user
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.UITable.Data=app.images';                  % Displays the directory information to the UITable.

        end

        % Button pushed function: SelectInputMaskImagesButton
        function SelectInputMaskImagesButtonPushed(app, event)
            [app.masks, app.masksFolder]=uigetfile({'*.jpg;*.png;*.tif;*.bmp;',...
    'Images Files (*.jpg;*.png;*.tif;*.bmp)'}, 'MultiSelect', 'on');  
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';
            app.UITable_2.Data=app.masks';                  % Displays the directory information to the UITable.

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
          
          app.done = 1;
        end

        % Value changed function: SegmentationSwitch
        function SegmentationSwitchValueChanged(app, event)
            value = app.SegmentationSwitch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ClosingCountourDropDownLabel = uilabel(app.UIFigure);
            app.ClosingCountourDropDownLabel.Text = 'Closing Countour';
            app.ClosingCountourDropDown = uidropdown(app.UIFigure);
            app.ClosingCountourDropDown.Items = {'hull', 'linear'};
            app.ClosingCountourDropDown.Value = 'hull';
            app.ProcessingoptionsLabel = uilabel(app.UIFigure);
            app.ProcessingoptionsLabel.Text = 'Processing options';
            app.FilterDropDownLabel = uilabel(app.UIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'None', 'Median', 'Wavelet'};
            app.FilterDropDown.Value = 'None';
            app.DataLabel = uilabel(app.UIFigure);
            app.DataLabel.Text = 'Data';
            app.RatioPixelMeterEditFieldLabel = uilabel(app.UIFigure);
            app.RatioPixelMeterEditFieldLabel.Text = 'Ratio Pixel Meter';
            app.RatioPixelMeterEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeometryLabel = uilabel(app.UIFigure);
            app.GeometryLabel.Text = 'Geometry';
            app.DistancebetweenslicesmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancebetweenslicesmEditFieldLabel.Text = 'Distance between slices (m)';
            app.DistancebetweenslicesmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectOutputfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectOutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputfolderButtonPushed, true);
            app.SelectOutputfolderButton.Text = 'Select Output folder';
            app.SelectInputUSImagesButton = uibutton(app.UIFigure, 'push');
            app.SelectInputUSImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputUSImagesButtonPushed2, true);
            app.SelectInputUSImagesButton.Text = 'Select Input US Images';
            app.SelectInputMaskImagesButton = uibutton(app.UIFigure, 'push');
            app.SelectInputMaskImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputMaskImagesButtonPushed, true);
            app.SelectInputMaskImagesButton.Text = 'Select Input Mask Images';
            app.SaveMasksCheckBox = uicheckbox(app.UIFigure);
            app.SaveMasksCheckBox.Text = 'Save Masks';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'US images'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Mask Images'};
            app.UITable_2.RowName = {};
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SegmentationSwitchLabel = uilabel(app.UIFigure);
            app.SegmentationSwitchLabel.Text = 'Segmentation';
            app.SegmentationSwitch = uiswitch(app.UIFigure, 'slider');
            app.SegmentationSwitch.Items = {'Tumor', 'Layer'};
            app.SegmentationSwitch.ValueChangedFcn = createCallbackFcn(app, @SegmentationSwitchValueChanged, true);
            app.SegmentationSwitch.Value = 'Tumor';
            app.LayerTumorEditFieldLabel = uilabel(app.UIFigure);
            app.LayerTumorEditFieldLabel.Text = '# Layer/ # Tumor';
            app.LayerTumorEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentationInterface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end