classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClosingCountourDropDownLabel    matlab.ui.control.Label
        ClosingCountourDropDown         matlab.ui.control.DropDown
        ProcessingoptionsLabel          matlab.ui.control.Label
        FilterDropDownLabel             matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        DataLabel                       matlab.ui.control.Label
        RatioPixelMeterEditFieldLabel   matlab.ui.control.Label
        RatioPixelMeterEditField        matlab.ui.control.NumericEditField
        GeometryLabel                   matlab.ui.control.Label
        DistancebetweenlayermEditFieldLabel  matlab.ui.control.Label
        DistancebetweenlayermEditField  matlab.ui.control.NumericEditField
        OutputfolderButton              matlab.ui.control.Button
        InputUSImagesButton             matlab.ui.control.Button
        InputMaskImagesButton           matlab.ui.control.Button
        SaveMasksCheckBox               matlab.ui.control.CheckBox
        UITable                         matlab.ui.control.Table
        UITable_2                       matlab.ui.control.Table
        OutputLabel                     matlab.ui.control.Label
    end

    
    methods (Access = public)
        
       % Construct app
        function app = ExampleDirectoryApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end
    end


    methods (Access = private)

        % Button pushed function: OutputfolderButton
        function OutputfolderButtonPushed(app, event)
           path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.OutputfolderButton.Text=path;  % Sets the label text to be the selected path
      

        end

        % Button pushed function: InputUSImagesButton
        function InputUSImagesButtonPushed2(app, event)
            images=uigetfile({'*.jpg;*.png;*.tif;*.bmp;',...
    'Images Files (*.jpg;*.png;*.tif;*.bmp)'}, 'MultiSelect', 'on');                    % Calls 'uigetdir' to obtain the directory location from the user
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.UITable.Data=images';                  % Displays the directory information to the UITable.

        end

        % Button pushed function: InputMaskImagesButton
        function InputMaskImagesButtonPushed(app, event)
            masks=uigetfile({'*.jpg;*.png;*.tif;*.bmp;',...
    'Images Files (*.jpg;*.png;*.tif;*.bmp)'}, 'MultiSelect', 'on');  
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';
            app.UITable_2.Data=masks';                  % Displays the directory information to the UITable.

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ClosingCountourDropDownLabel = uilabel(app.UIFigure);
            app.ClosingCountourDropDownLabel.Text = 'Closing Countour';
            app.ClosingCountourDropDown = uidropdown(app.UIFigure);
            app.ClosingCountourDropDown.Items = {'Hull', 'Linear'};
            app.ClosingCountourDropDown.Value = 'Hull';
            app.ProcessingoptionsLabel = uilabel(app.UIFigure);
            app.ProcessingoptionsLabel.Text = 'Processing options';
            app.FilterDropDownLabel = uilabel(app.UIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'None', 'Median', 'Wavelet'};
            app.FilterDropDown.Value = 'None';
            app.DataLabel = uilabel(app.UIFigure);
            app.DataLabel.Text = 'Data';
            app.RatioPixelMeterEditFieldLabel = uilabel(app.UIFigure);
            app.RatioPixelMeterEditFieldLabel.Text = 'Ratio Pixel Meter';
            app.RatioPixelMeterEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeometryLabel = uilabel(app.UIFigure);
            app.GeometryLabel.Text = 'Geometry';
            app.DistancebetweenlayermEditFieldLabel = uilabel(app.UIFigure);
            app.DistancebetweenlayermEditFieldLabel.Text = 'Distance between layer (m)';
            app.DistancebetweenlayermEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputfolderButton = uibutton(app.UIFigure, 'push');
            app.OutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputfolderButtonPushed, true);
            app.OutputfolderButton.Text = 'Output folder';
            app.InputUSImagesButton = uibutton(app.UIFigure, 'push');
            app.InputUSImagesButton.ButtonPushedFcn = createCallbackFcn(app, @InputUSImagesButtonPushed2, true);
            app.InputUSImagesButton.Text = 'Input US Images';
            app.InputMaskImagesButton = uibutton(app.UIFigure, 'push');
            app.InputMaskImagesButton.ButtonPushedFcn = createCallbackFcn(app, @InputMaskImagesButtonPushed, true);
            app.InputMaskImagesButton.Text = 'Input Mask Images';
            app.SaveMasksCheckBox = uicheckbox(app.UIFigure);
            app.SaveMasksCheckBox.Text = 'Save Masks';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'US images'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Mask Images'};
            app.UITable_2.RowName = {};
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end